classdef GoogleDriveAuthorizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Instructions       matlab.ui.control.Label
        RefreshTokenLabel  matlab.ui.control.Label
        RefreshToken       matlab.ui.control.EditField
        TokenDescription   matlab.ui.control.Label
        Exit               matlab.ui.control.Button
    end


    properties (Access = private)
        baseApp Autograder;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, base)
            % Step 1: Get Device Verification Code
            app.baseApp = base;
            try
                app.baseApp.driveToken = authorizeWithGoogle();
                app.RefreshToken.Value = app.baseApp.driveToken;
            catch
                % what do we do?
                uialert(app.UIFigure, ...
                    'We''re having trouble authenticating you... Try again later (Are you connected to the internet?', ...
                    'Google Drive Authentication');
            end
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            uiresume(app.UIFigure);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Instructions = uilabel(app.UIFigure);
            app.Instructions.Text = {'To set up your access to Google Drive, '; 'We need you to allow the autograder to access your Drive folder.'};
            app.RefreshTokenLabel = uilabel(app.UIFigure);
            app.RefreshTokenLabel.Text = 'Token';
            app.RefreshToken = uieditfield(app.UIFigure, 'text');
            app.TokenDescription = uilabel(app.UIFigure);
            app.TokenDescription.Text = 'Keep this token safe - you''ll need it if you ever want to Access Google Drive again';
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GoogleDriveAuthorizer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end