classdef Initializer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Title     matlab.ui.control.Label
        Start     matlab.ui.control.Button
    end


    properties (Access = private)
        base Autograder;
        INIT_PATH = '/httpdocs/init.autograde';
    end

    methods (Access = private)
    
        function downloadSettings(app, user, pass)
            javaaddpath([fileparts(fileparts(mfilename('fullpath'))) filesep 'networking' filesep 'JSch.jar']);
            cleaner = onCleanup(@()...
                (javarmpath([fileparts(fileparts(mfilename('fullpath'))) filesep 'networking' filesep 'JSch.jar'])));
            sftp = app.getSftp(user, pass);
            sftp.get(app.INIT_PATH, app.base.settingsPath);
        end
    
        function sftp = getSftp(~, user, pass)
            import com.jcraft.jsch.*;
            controller = JSch;
            HOST = 'cs1371.gatech.edu';
            PORT = 22;
            session = controller.getSession(user, HOST, PORT);
            session.setConfig("PreferredAuthentications", "password");
            session.setConfig("StrictHostKeyChecking", "no");
            session.setPassword(pass);
            try
                session.connect();
            catch reason
                e = MException('AUTOGRADER:networking:connectionError', ...
                    'Unable to connect');
                e = e.addCause(reason);
                throw(e);
            end
            
            sftp = session.openChannel("sftp");
            sftp.connect();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, baseApp)
            app.base = baseApp;
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            % authorize with the server
            auth = ServerAuthorizer(app.base);
            uiwait(auth.UIFigure);
            user = auth.ServerUsername.Value;
            pass = auth.ServerPassword.Value;
            close(auth.UIFigure);
            % download settings
            p = uiprogressdlg(app.UIFigure, 'Cancelable', 'off', 'Indeterminate', 'on', ...
                'Title', 'Contacting Server...', 'Message', 'Downloading Initial Settings...', ...
                'ShowPercentage', 'off');
            try
                app.downloadSettings(user, pass);
            catch
                uialert(app.UIFigure, ...
                    'Either the given credentials are incorrect, or there''s something wrong with your connection (are you on Georgia Tech WiFi?)', ...
                    'Authentication Failure');
                return;
            end
            close(p);
            % reload settings
            app.base.parsePreferences();
            uiresume(app.UIFigure);
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Welcome';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = {'Welcome to the autograder!'; 'To get started, you''ll need'; 'to authenticate first...'};
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'Get Started';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Initializer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end