classdef Notifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Title                    matlab.ui.control.Label
        EmailPanel               matlab.ui.container.Panel
        Email                    matlab.ui.control.Switch
        ToLabel                  matlab.ui.control.Label
        To                       matlab.ui.control.EditField
        SlackPanel               matlab.ui.container.Panel
        Slack                    matlab.ui.control.Switch
        ChannelLabel             matlab.ui.control.Label
        Channel                  matlab.ui.control.DropDown
        TextMessagingPanel       matlab.ui.container.Panel
        Text                     matlab.ui.control.Switch
        NumberLabel              matlab.ui.control.Label
        Number                   matlab.ui.control.EditField
        PostCanvasPanel          matlab.ui.container.Panel
        Post                     matlab.ui.control.Switch
        ConfigurePost            matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        TestConfigurationButton  matlab.ui.control.Button
    end


    properties (Access = private)
        base Autograder % Description
        canvasMessage char % Description
        canvasTitle char
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, baseApp)
            app.base = baseApp;
            % Email
            if ~isempty(app.base.email)
                app.Email.Value = 'On';
                app.To.Value = app.base.email;
            end
            % Slack
            if ~isempty(app.base.slackChannel)
                app.Slack.Value = 'On';
                app.Channel.Value = app.base.slackChannel;
            end
            % Text
            if ~isempty(app.base.phoneNumber)
                app.Text.Value = 'On';
                app.Number.Value = app.base.phoneNumber;
            end
            % Canvas
            if ~isempty(app.base.canvasTitle)
                app.Post.Value = 'On';
            end
        end

        % Button pushed function: ConfigurePost
        function ConfigurePostButtonPushed(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if strcmp(app.Email.Value, 'On')
                app.base.email = app.To.Value;
            else
                app.base.email = '';
            end
            
            if strcmp(app.Slack.Value, 'On')
                app.base.slackChannel = app.Channel.Value;
            else
                app.base.slackChannel = '';
            end
            
            if strcmp(app.Text.Value, 'On')
                app.base.phoneNumber = app.Number.Value;
            else
                app.base.phoneNumber = '';
            end
            
            if strcmp(app.Post.Value, 'On')
                app.base.canvasMessage = app.canvasMessage;
                app.base.canvasTitle = app.canvasTitle;
            else
                app.base.canvasMessage = '';
                app.base.canvasTitle = '';
            end
            uiresume(app.UIFigure);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Notifications';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Notification Settings';
            app.EmailPanel = uipanel(app.UIFigure);
            app.EmailPanel.Title = 'Email';
            app.Email = uiswitch(app.EmailPanel, 'slider');
            app.ToLabel = uilabel(app.EmailPanel);
            app.ToLabel.Text = 'To:';
            app.To = uieditfield(app.EmailPanel, 'text');
            app.SlackPanel = uipanel(app.UIFigure);
            app.SlackPanel.Title = 'Slack';
            app.Slack = uiswitch(app.SlackPanel, 'slider');
            app.ChannelLabel = uilabel(app.SlackPanel);
            app.ChannelLabel.Text = 'Channel';
            app.Channel = uidropdown(app.SlackPanel);
            app.Channel.Items = {};
            app.Channel.Editable = 'on';
            app.Channel.Value = {};
            app.TextMessagingPanel = uipanel(app.UIFigure);
            app.TextMessagingPanel.Title = 'Text Messaging';
            app.Text = uiswitch(app.TextMessagingPanel, 'slider');
            app.NumberLabel = uilabel(app.TextMessagingPanel);
            app.NumberLabel.Text = 'Phone Number';
            app.Number = uieditfield(app.TextMessagingPanel, 'text');
            app.PostCanvasPanel = uipanel(app.UIFigure);
            app.PostCanvasPanel.Title = 'Post to Canvas';
            app.Post = uiswitch(app.PostCanvasPanel, 'slider');
            app.ConfigurePost = uibutton(app.PostCanvasPanel, 'push');
            app.ConfigurePost.ButtonPushedFcn = createCallbackFcn(app, @ConfigurePostButtonPushed, true);
            app.ConfigurePost.Text = 'Configure...';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TestConfigurationButton = uibutton(app.UIFigure, 'push');
            app.TestConfigurationButton.Text = 'Test Configuration...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Notifier(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end